classdef Interface_graphique_du_vocodeur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConsignesEditField       matlab.ui.control.EditField
        ConsignesEditFieldLabel  matlab.ui.control.Label
        effets                   matlab.ui.control.DropDown
        EffetsDropDownLabel      matlab.ui.control.Label
        fichier                  matlab.ui.control.DropDown
        AudioDropDownLabel       matlab.ui.control.Label
        ecouter                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CurrentAudioData     double % Pour stocker le signal audio acutel
        CurrentFs            double % Pour stocker la fréquence d'échantillonnage % Description
        y_signal             double % Pour stocker le signal audio
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: fichier
        function fichierValueChanged(app, event)
            value = app.fichier.Value;
            
            % Obtenez le chemin du répertoire de l'application
            appDir = fileparts(mfilename('fullpath')); % Obtenez le répertoire du fichier App Designer
            
            % Construisez le chemin complet du dossier audio
            audioFolderPath = fullfile(appDir, 'Audio');

            % Construisez le chemin complet du fichier audio
            audioFilePath = fullfile(audioFolderPath, value);
    
            % Vérifiez si le fichier existe avant de tenter de le charger
            if exist(audioFilePath, 'file')
                [y, Fs] = audioread(audioFilePath);
        
                % Stockez les données chargées dans les propriétés de l'app pour une utilisation ultérieure
                app.CurrentAudioData = y;
                app.y_signal = y;
                app.CurrentFs = Fs;
            else
                uialert(app.UIFigure, sprintf('Le fichier "%s" n''existe pas.', value), 'Fichier non trouvé');
            end
            effetsValueChanged(app);
        end

        % Button pushed function: ecouter
        function ecouterButtonPushed(app, event)
            if ~isempty(app.CurrentAudioData) && ~isempty(app.CurrentFs)
                sound(app.CurrentAudioData, app.CurrentFs);
            else
                uialert(app.UIFigure, 'Aucun fichier audio chargé.', 'Erreur');
            end
        end

        % Value changed function: effets
        function effetsValueChanged(app, event)

            selectedEffect = app.effets.Value;

            if nargin < 2  % Vérifie si la fonction a été appelée sans argument 'event'
                selectedEffect = app.effets.Value;
            else
                selectedEffect = event.Value; % Ou utilisez cette ligne si 'event' est toujours passé
            end
        
            % Obtenez l'audio actuellement chargé depuis votre application
            y = app.y_signal;
            % Récupérez la fréquence d'échantillonnage depuis votre application (vous devez adapter cette partie en fonction de votre application)
            Fs = app.CurrentFs;
            if strcmp(selectedEffect, 'Aucun')
                app.CurrentAudioData = y;

            elseif strcmp(selectedEffect, 'Ralenti')
                rapp = 2/3;
                app.CurrentAudioData = PVoc(y,rapp,1024);

            elseif strcmp(selectedEffect, 'Accéléré')
                rapp = 3/2;
                app.CurrentAudioData = PVoc(y,rapp,1024);
            
            elseif strcmp(selectedEffect, 'Aigu')
                % Nombre de points pour la FFT/IFFT
                Nfft = 256;
                % Nombre de points (longueur) de la fenêtre de pondération 
                % (par défaut fenêtre de Hanning)
                Nwind = Nfft;
                % Augmentation 
                %--------------
                a = 2;  b = 3;  %peut être modifié
                yvoc_high = PVoc(y, a/b, Nfft, Nwind);
                app.CurrentAudioData = resample(yvoc_high, a, b);
                
            elseif strcmp(selectedEffect, 'Grave')
                % Nombre de points pour la FFT/IFFT
                Nfft = 256;
                % Nombre de points (longueur) de la fenêtre de pondération 
                % (par défaut fenêtre de Hanning)
                Nwind = Nfft;
                % Augmentation 
                %--------------
                a = 3;  b = 2;  %peut être modifié
                yvoc_low = PVoc(y, a/b, Nfft, Nwind);
                app.CurrentAudioData = resample(yvoc_low, a, b);
                
            elseif strcmp(selectedEffect, 'Robot')
                % Appliquez l'effet robotique à audioData
                app.CurrentAudioData = Rob(y,500,Fs);
           
            elseif strcmp(selectedEffect, 'Saturation')
                app.CurrentAudioData = Saturation(y, 30);
          
            elseif strcmp(selectedEffect, 'Reverbe')
                app.CurrentAudioData = Reverb(y, 0.5, 50, Fs);

            elseif strcmp(selectedEffect, 'Retro')
                app.CurrentAudioData = Bitcrusher(y, 4);
            
            
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ecouter = uibutton(app.UIFigure, 'push');
            app.ecouter.ButtonPushedFcn = createCallbackFcn(app, @ecouterButtonPushed, true);
            app.ecouter.Text = 'Ecouter l''audio';
            app.AudioDropDownLabel = uilabel(app.UIFigure);
            app.AudioDropDownLabel.Text = 'Audio';
            app.fichier = uidropdown(app.UIFigure);
            app.fichier.Items = {'Choisissez un audio', 'extrait_cours.wav', 'Diner.wav', 'Extrait.wav', 'Halleluia.wav'};
            app.fichier.ValueChangedFcn = createCallbackFcn(app, @fichierValueChanged, true);
            app.fichier.Value = 'Choisissez un audio';
            app.EffetsDropDownLabel = uilabel(app.UIFigure);
            app.EffetsDropDownLabel.Text = 'Effets';
            app.effets = uidropdown(app.UIFigure);
            app.effets.Items = {'Choisissez un effet', 'Aucun', 'Ralenti', 'Accéléré', 'Aigu', 'Grave', 'Robot', 'Saturation', 'Reverbe', 'Retro'};
            app.effets.ValueChangedFcn = createCallbackFcn(app, @effetsValueChanged, true);
            app.effets.Value = 'Choisissez un effet';
            app.ConsignesEditFieldLabel = uilabel(app.UIFigure);
            app.ConsignesEditFieldLabel.Text = 'Consignes';
            app.ConsignesEditField = uieditfield(app.UIFigure, 'text');
            app.ConsignesEditField.Value = 'Veuillez sélectionner un audio et un effet et ensuite ecoutez l''audio !';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface_graphique_du_vocodeur

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end